classdef orotron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OrotronUIFigure      matlab.ui.Figure
        NeEditFieldLabel     matlab.ui.control.Label
        NeEditField          matlab.ui.control.NumericEditField
        LzEditFieldLabel     matlab.ui.control.Label
        LzEditField          matlab.ui.control.NumericEditField
        TendEditFieldLabel   matlab.ui.control.Label
        TendEditField        matlab.ui.control.NumericEditField
        dtEditFieldLabel     matlab.ui.control.Label
        dtEditField          matlab.ui.control.NumericEditField
        DeltaEditFieldLabel  matlab.ui.control.Label
        DeltaEditField       matlab.ui.control.NumericEditField
        dzEditFieldLabel     matlab.ui.control.Label
        dzEditField          matlab.ui.control.NumericEditField
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        Time0Label           matlab.ui.control.Label
        IEditFieldLabel      matlab.ui.control.Label
        IEditField           matlab.ui.control.NumericEditField
        ExitButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Ne = 256 % Description
        Lz = 10 % Description
        Tend = 5 % Description        
        Delta = 0 % Description
        I = 20 % Description
        dz = 0.002 % Description
        dt = 0.001 % Description
        INTERVALT % Description
        INTERVALZ % Description
        OUTB % Description
        OUTJ % Description
        ZAxis % Description
        TAxis % Description
        ExecutionTime % Description                
    end
    
    properties (Access = public)
        Stop = 0 % Description
        %         wb % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NeEditField
        function NeEditFieldValueChanged(app, event)
            app.Ne = app.NeEditField.Value;
        end

        % Value changed function: LzEditField
        function LzEditFieldValueChanged(app, event)
            app.Lz = app.LzEditField.Value;
        end

        % Value changed function: TendEditField
        function TendEditFieldValueChanged(app, event)
            app.Tend = app.TendEditField.Value;
        end

        % Value changed function: DeltaEditField
        function DeltaEditFieldValueChanged(app, event)
            app.Delta = app.DeltaEditField.Value;
        end

        % Value changed function: dzEditField
        function dzEditFieldValueChanged(app, event)
            app.dz = app.dzEditField.Value;
        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
            app.dt = app.dtEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            
            IN.Lz = app.Lz;
            IN.Tend = app.Tend;
            IN.DeltaZ = app.dz;
            IN.DeltaT = app.dt;
            IN.Ne = app.Ne;
            IN.Delta = app.Delta;
            IN.I = app.I;
            
            IN.ZAxis(:,1) = 0:IN.DeltaZ:IN.Lz;
            IN.TAxis(:,1) = 0:IN.DeltaT:IN.Tend;
            
            app.INTERVALT = fix(size(IN.TAxis,1)/500);
            app.INTERVALZ = fix(size(IN.ZAxis,1)/500);
            %             app.INTERVALT = 1;
            %             app.INTERVALZ = 1;
            if app.INTERVALT < 1
                errordlg('Too small Tend', 'Error',"replace");
                uiwait;
                delete(app);
                return;
            end
            if app.INTERVALZ < 1
                errordlg('Too small Zend', 'Error',"replace");
                uiwait;
                delete(app);
                return;
            end
            IN.INTERVALT = app.INTERVALT;
            IN.INTERVALZ = app.INTERVALZ;
            
            ZBEG = 0;
            ZEND = 0.5;
            IN.InitialField = zeros(length(IN.ZAxis),1);
            IND1 = (IN.ZAxis > ZBEG & IN.ZAxis < ZEND);
            IN.InitialField(IND1,1) = 0.001*sin(pi * (IN.ZAxis(IND1) - ZBEG) / (ZEND - ZBEG)).^2;
            
            %             InitialField(:,1) = zeros(length(IN.ZAxis),1);
            %             IND1 = IN.ZAxis <= IN.Lz/10;
            %             InitialField(IND1,1) = 0.1 * sin(pi / IN.Lz * 10 * IN.ZAxis(IND1));
            %             IN.InitialField(:,1) = InitialField(:,1);
            
            axB = app.UIAxes;
            axJ = app.UIAxes2;
            
            axB.XLim = [0 IN.ZAxis(end,1)];
            axJ.XLim = [0 IN.ZAxis(end,1)];
            
            lHandleB = line(axB, nan, nan);
            lHandleB.XData = IN.ZAxis(:,1);
            lHandleB.YData = abs(IN.InitialField(:,1));
            lHandleB.Color = 'black';
            lHandleB.LineWidth = 1;
            
            lHandleJ = line(axJ, nan, nan);
            lHandleJ.XData = IN.ZAxis(:,1);
            lHandleJ.YData = zeros(length(IN.ZAxis(:,1)),1);
            lHandleJ.Color = 'black';
            lHandleJ.LineWidth = 1;
            
            %             IN.axB = axB;
            %             IN.axJ = axJ;
            IN.lHandleB = lHandleB;
            IN.lHandleJ = lHandleJ;
            
            %             app.wb = waitbar(0,'Progress...','Name','Progress...');
            
            OUT = oro(IN, app);
            
            %             waitbar(1,app.wb,'Finishing');
            
            app.OUTB = OUT.OUTB;
            app.OUTJ = OUT.OUTJ;            
            app.ZAxis = OUT.ZAxis;
            
            if app.Stop > 0
                app.TAxis = OUT.TAxis(1:app.Stop);
                app.OUTB = app.OUTB(:,1:app.Stop);
                app.OUTJ = app.OUTJ(:,1:app.Stop);
                %                 close(app.wb);
                %                 size(app.OUTB)
                %                 app.Stop
            else
                app.TAxis = OUT.TAxis;
                app.ExecutionTime = OUT.ExecutionTime;
                %                 close(app.wb);
            end
            
            app.Stop = 1;
            app.SaveButton.Enable = 'on';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = 1;
            app.SaveButton.Enable = 'on';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            RES.Ne = app.Ne;
            RES.Tend = app.Tend;
            RES.Lz = app.Lz;
            RES.Delta = app.Delta;
            RES.I = app.I;
            RES.dz = app.dz;
            RES.dt = app.dt;
            RES.INTERVALT = app.INTERVALT;
            
            % Вывод в .mat файл
%             RES.OUTB = app.OUTB;
%             RES.OUTJ = app.OUTJ;
%             RES.ZAxis = app.ZAxis;
%             RES.TAxis = app.TAxis;
            ZAxis = app.ZAxis;
            TAxis = app.TAxis;
            
%             % Вывод в .dat файл
            OUTBvsZ = zeros(size(app.OUTB,1), 2*size(app.OUTB,2));
            OUTJvsZ = zeros(size(app.OUTJ,1), 2*size(app.OUTJ,2));
            OUTBvsZ(:,1:2:end-1) = real(app.OUTB);
            OUTBvsZ(:,2:2:end) = imag(app.OUTB);
            OUTJvsZ(:,1:2:end-1) = real(app.OUTJ);
            OUTJvsZ(:,2:2:end) = imag(app.OUTJ);
            
            app.OUTB = app.OUTB.';
            app.OUTJ = app.OUTJ.';
            OUTBvsT = zeros(size(app.OUTB,1), 2*size(app.OUTB,2));
            OUTJvsT = zeros(size(app.OUTJ,1), 2*size(app.OUTJ,2));
            OUTBvsT(:,1:2:end-1) = real(app.OUTB);
            OUTBvsT(:,2:2:end) = imag(app.OUTB);
            OUTJvsT(:,1:2:end-1) = real(app.OUTJ);
            OUTJvsT(:,2:2:end) = imag(app.OUTJ); 
            
            OUTBvsZ = [ZAxis OUTBvsZ];
            OUTJvsZ = [ZAxis OUTJvsZ];
            
            OUTBvsT = [TAxis OUTBvsT];
            OUTJvsT = [TAxis OUTJvsT];                       
            
            
            if (~isempty(app.ExecutionTime))
                RES.ExecutionTime = app.ExecutionTime;
            end
            
%             if (isempty(app.TAxis) || isempty(OUTBvsT))
%                 msgbox('First press the button "Solve"!')
%             else
                Folder = 'results/';
                
                hash = datestr(now,30);
                FolderName=sprintf('%s%s', Folder, hash);
                mkdir(FolderName);
                
                % Вывод в .mat файл
%                 fileResults = sprintf('%s/%s', FolderName, 'results.mat');
%                 save(fileResults,"RES","-v7.3");
                
                % Вывод в .dat файл
                fileParameters = sprintf('%s/%s', FolderName, 'parameters.txt');%                
                fileResultsBvsZ = sprintf('%s/%s', FolderName, 'resultsBvsZ.dat');
                fileResultsJvsZ = sprintf('%s/%s', FolderName, 'resultsJvsZ.dat');
                fileResultsBvsT = sprintf('%s/%s', FolderName, 'resultsBvsT.dat');
                fileResultsJvsT = sprintf('%s/%s', FolderName, 'resultsJvsT.dat');
                fileT = sprintf('%s/%s', FolderName, 'Time.dat');
                fileZ = sprintf('%s/%s', FolderName, 'Z.dat');
                
                strParam = 'Ne = %d\nTend = %f\nLz = %f\nDelta = %f\nI = %f\ndz = %f\ndt = %f\n';
                fileID = fopen(fileParameters ,'wt');
                fprintf(fileID, strParam, RES.Ne, RES.Tend, RES.Lz, RES.Delta, RES.I, RES.dz, RES.dt);
                fclose(fileID);
                
                save(fileResultsBvsZ, 'OUTBvsZ', '-ascii');
                save(fileResultsJvsZ, 'OUTJvsZ', '-ascii');
                save(fileResultsBvsT, 'OUTBvsT', '-ascii');
                save(fileResultsJvsT, 'OUTJvsT', '-ascii');
                save(fileT, 'TAxis', '-ascii');
                save(fileZ, 'ZAxis', '-ascii');
                
                %                 [file, path] = uiputfile('results.mat');
                %                 if (file ~= 0)
                %                     %                     save([path file],'sol','-ascii');
                %                     save([path file],"RES","-v7.3");
                %                 end
                %             end
                %             app.SaveButton.Enable = 'off';
%             end
            app.SaveButton.Enable = 'off';
            app.ExitButton.Enable = 'on';           
        end

        % Close request function: OrotronUIFigure
        function OrotronUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.OrotronUIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                %                 close(app.wb);
                delete(app)
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            app.I = app.IEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OrotronUIFigure = uifigure('Visible', 'off');
            app.OrotronUIFigure.Name = 'Orotron';
            app.OrotronUIFigure.CloseRequestFcn = createCallbackFcn(app, @OrotronUIFigureCloseRequest, true);
            app.NeEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.NeEditFieldLabel.Text = 'Ne';
            app.NeEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.NeEditField.ValueChangedFcn = createCallbackFcn(app, @NeEditFieldValueChanged, true);
            app.NeEditField.Value = 256;
            app.LzEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.LzEditFieldLabel.Text = 'Lz';
            app.LzEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.LzEditField.ValueChangedFcn = createCallbackFcn(app, @LzEditFieldValueChanged, true);
            app.LzEditField.Value = 10;
            app.TendEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.TendEditFieldLabel.Text = 'Tend';
            app.TendEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.TendEditField.ValueChangedFcn = createCallbackFcn(app, @TendEditFieldValueChanged, true);
            app.TendEditField.Value = 5;
            app.dtEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.dtEditField.Value = 0.001;
            app.DeltaEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.DeltaEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaEditFieldValueChanged, true);
            app.dzEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.dzEditField.ValueChangedFcn = createCallbackFcn(app, @dzEditFieldValueChanged, true);
            app.dzEditField.Value = 0.002;
            app.UIAxes = uiaxes(app.OrotronUIFigure);
            title(app.UIAxes, 'B')
            xlabel(app.UIAxes, 'z')
            ylabel(app.UIAxes, 'B')
            app.UIAxes2 = uiaxes(app.OrotronUIFigure);
            title(app.UIAxes2, 'J')
            xlabel(app.UIAxes2, 'z')
            ylabel(app.UIAxes2, 'J')
            app.StartButton = uibutton(app.OrotronUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.OrotronUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.OrotronUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.Time0Label = uilabel(app.OrotronUIFigure);
            app.Time0Label.Text = 'Time = 0';
            app.IEditFieldLabel = uilabel(app.OrotronUIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.OrotronUIFigure, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.IEditField.Value = 20;
            app.ExitButton = uibutton(app.OrotronUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.OrotronUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orotron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OrotronUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OrotronUIFigure)
        end
    end
end